classdef simulator_GUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        TabGroup                   matlab.ui.container.TabGroup
        SettingsTab                matlab.ui.container.Tab
        NmLabel                    matlab.ui.control.Label
        Image2                     matlab.ui.control.Image
        NsmLabel                   matlab.ui.control.Label
        sLabel                     matlab.ui.control.Label
        Exoesqueletode6GDLLabel    matlab.ui.control.Label
        SimuladorDinmicoLabel      matlab.ui.control.Label
        Image                      matlab.ui.control.Image
        friction_disp              matlab.ui.control.NumericEditField
        prev_tq6                   matlab.ui.control.Spinner
        Friction                   matlab.ui.control.Knob
        FactordedisipacinLabel     matlab.ui.control.Label
        prev_tq5                   matlab.ui.control.Spinner
        prev_tq4                   matlab.ui.control.Spinner
        prev_tq3                   matlab.ui.control.Spinner
        prev_tq2                   matlab.ui.control.Spinner
        prev_tq1                   matlab.ui.control.Spinner
        TipodesistemaButtonGroup   matlab.ui.container.ButtonGroup
        NoconservativoButton       matlab.ui.control.ToggleButton
        ConservativoButton         matlab.ui.control.ToggleButton
        SimulationTime             matlab.ui.control.NumericEditField
        TiempodesimulacinEditFieldLabel  matlab.ui.control.Label
        TorquesLabel               matlab.ui.control.Label
        ReestablecertorquesButton  matlab.ui.control.Button
        tau6                       matlab.ui.control.Slider
        Label_7                    matlab.ui.control.Label
        tau5                       matlab.ui.control.Slider
        Label_6                    matlab.ui.control.Label
        tau4                       matlab.ui.control.Slider
        Label_5                    matlab.ui.control.Label
        tau3                       matlab.ui.control.Slider
        Label_4                    matlab.ui.control.Label
        tau2                       matlab.ui.control.Slider
        Label_3                    matlab.ui.control.Label
        SimularButton              matlab.ui.control.Button
        tau1                       matlab.ui.control.Slider
        Label_2                    matlab.ui.control.Label
        VariablesdeEstadoTab       matlab.ui.container.Tab
        Label                      matlab.ui.control.Label
        graph_position             matlab.ui.control.UIAxes
        graph_velocity             matlab.ui.control.UIAxes
        EnergyAnalysisTab          matlab.ui.container.Tab
        graph_energy_potential     matlab.ui.control.UIAxes
        graph_energy_kinetic       matlab.ui.control.UIAxes
        EnergaMecnicaTab           matlab.ui.container.Tab
        graph_energy_mechanic      matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        friction_tmp
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            clear all; close all; clc
        end

        % Button pushed function: SimularButton
        function SimularButtonPushed(app, event)
            clear All
            tic
            app.SimularButton.Enable = 'off';
            app.SimularButton.Text = 'Simulando ... ';
            app.SimularButton.BackgroundColor = [0.00,0.62,0.58];
            
            hold(app.graph_energy_kinetic,'off')
            tq1 = app.tau1.Value;
            tq2 = app.tau2.Value;
            tq3 = app.tau3.Value;
            tq4 = app.tau4.Value;
            tq5 = app.tau5.Value;
            tq6 = app.tau6.Value;

            torque = [0 tq1 tq2 tq3 tq4 tq5 tq6];

            assignin("base", "torque", torque);

            app.friction_tmp = app.Friction.Value;
            selectedButton = app.TipodesistemaButtonGroup.SelectedObject;
            
            if selectedButton.Text == "Conservativo"
                assignin("base", "dissipative", [0 1e-4]);
                
            else
                assignin("base", "dissipative", [0 app.friction_tmp]);
            end

            sim_out = sim('BDA_DynamicModel.slx', 'StopTime', num2str(app.SimulationTime.Value));

            plot(app.graph_position, sim_out.position.Time, sim_out.position.Data);
            legend(app.graph_position, {'$q_1$','$q_2$','$q_3$','$q_4$','$q_5$','$q_6$'},'Interpreter','latex');
            plot(app.graph_velocity, sim_out.velocity.Time, sim_out.velocity.Data);
            legend(app.graph_velocity, {'$\dot{q}_1$','$\dot{q}_2$','$\dot{q}_3$','$\dot{q}_4$','$\dot{q}_5$','$\dot{q}_6$'},'Interpreter','latex');
            
            %Energy Analysis
            plot(app.graph_energy_kinetic, sim_out.kinetic_energy.Time, sim_out.kinetic_energy.Data);
                % Potential Energy Offset
            potential_energy = sim_out.potential_energy.Data;
            %h_matrix = sim_out.inertia_matrix.Data;

            offset = 0.154028;
            samples = length(potential_energy);
            potential_energy_offset = zeros(samples, 1);
            
            for i = 1:samples
                potential_energy_offset(i) = potential_energy(i) + offset;
            end
            mechanic_energy = sim_out.kinetic_energy.Data + potential_energy_offset;

            plot(app.graph_energy_potential, sim_out.potential_energy.Time, potential_energy_offset);
            plot(app.graph_energy_mechanic, sim_out.potential_energy.Time, mechanic_energy);
            hold(app.graph_energy_mechanic, 'on')
            plot(app.graph_energy_mechanic, sim_out.potential_energy.Time, potential_energy_offset);
            plot(app.graph_energy_mechanic, sim_out.potential_energy.Time, sim_out.kinetic_energy.Data);
            legend(app.graph_energy_mechanic, {'Energía Mecánica', 'Energía Potencial', 'Energía Cinética'})
            hold(app.graph_energy_mechanic, 'off')
%             assignin('base','h_matrix',h_matrix)
%             save('inertia_matrix','h_matrix')
            
            app.SimularButton.Enable = 'on';
            app.SimularButton.Text = 'Simular';
            app.SimularButton.BackgroundColor = [0.96,0.96,0.96];
            toc
        end

        % Button pushed function: ReestablecertorquesButton
        function ReestablecertorquesButtonPushed(app, event)
            app.tau1.Value = 0;
            app.tau2.Value = 0;
            app.tau3.Value = 0;
            app.tau4.Value = 0;
            app.tau5.Value = 0;
            app.tau6.Value = 0;

            app.prev_tq1.Value = 0;
            app.prev_tq2.Value = 0;
            app.prev_tq3.Value = 0;
            app.prev_tq4.Value = 0;
            app.prev_tq5.Value = 0;
            app.prev_tq6.Value = 0;
        end

        % Selection changed function: TipodesistemaButtonGroup
        function TipodesistemaButtonGroupSelectionChanged(app, event)
            selectedButton = app.TipodesistemaButtonGroup.SelectedObject;
            
            if selectedButton.Text == "Conservativo"
                app.Friction.Enable = 'Off';
                app.friction_disp.Enable = 'Off';
                app.friction_tmp = app.Friction.Value;
                app.friction_disp.Value = 0;
                app.Friction.Value = 0;
                app.SimulationTime.Value = 20;
                app.SimulationTime.Enable = 'Off';
            else
                app.Friction.Enable = 'On';
                app.friction_disp.Enable = 'On';
                app.Friction.Value = app.friction_tmp;
                app.friction_disp.Value = app.friction_tmp;
                app.SimulationTime.Enable = 'On';
                app.SimulationTime.Value = 10;
            end
        end

        % Value changing function: tau1
        function tau1ValueChanging(app, event)
            changingValue = event.Value;
            app.prev_tq1.Value = changingValue;
        end

        % Value changing function: prev_tq1
        function prev_tq1ValueChanging(app, event)
            app.tau1.Value = event.Value;
        end

        % Value changing function: tau2
        function tau2ValueChanging(app, event)
            app.prev_tq2.Value = event.Value;
        end

        % Value changing function: prev_tq2
        function prev_tq2ValueChanging(app, event)
            app.tau2.Value = event.Value;            
        end

        % Value changing function: tau3
        function tau3ValueChanging(app, event)
            app.prev_tq3.Value = event.Value;
        end

        % Value changing function: prev_tq3
        function prev_tq3ValueChanging(app, event)
            app.tau3.Value = event.Value;            
        end

        % Value changing function: tau4
        function tau4ValueChanging(app, event)
            app.prev_tq4.Value = event.Value;
        end

        % Value changing function: prev_tq4
        function prev_tq4ValueChanging(app, event)
            app.tau4.Value = event.Value;            
        end

        % Value changing function: tau5
        function tau5ValueChanging(app, event)
            app.prev_tq5.Value = event.Value;            
        end

        % Value changing function: prev_tq5
        function prev_tq5ValueChanging(app, event)
            app.tau5.Value = event.Value;            
        end

        % Value changing function: tau6
        function tau6ValueChanging(app, event)
            app.prev_tq6.Value = event.Value;            
        end

        % Value changing function: prev_tq6
        function prev_tq6ValueChanging(app, event)
            app.tau6.Value = event.Value;            
        end

        % Value changing function: Friction
        function FrictionValueChanging(app, event)
            app.friction_disp.Value = event.Value;            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.SettingsTab = uitab(app.TabGroup);
            app.SettingsTab.Title = 'Configuración';
            app.Label_2 = uilabel(app.SettingsTab);
            app.Label_2.Text = 'τ1';
            app.tau1 = uislider(app.SettingsTab);
            app.tau1.Limits = [-0.1 0.1];
            app.tau1.ValueChangingFcn = createCallbackFcn(app, @tau1ValueChanging, true);
            app.tau1.Tooltip = {'Torque para el primer grado de libertad.'};
            app.SimularButton = uibutton(app.SettingsTab, 'push');
            app.SimularButton.ButtonPushedFcn = createCallbackFcn(app, @SimularButtonPushed, true);
            app.SimularButton.Tooltip = {'Ejecución de simulación con los parámetros establecidos previamente.'};
            app.SimularButton.Text = 'Simular';
            app.Label_3 = uilabel(app.SettingsTab);
            app.Label_3.Text = 'τ2';
            app.tau2 = uislider(app.SettingsTab);
            app.tau2.Limits = [-0.1 0.1];
            app.tau2.ValueChangingFcn = createCallbackFcn(app, @tau2ValueChanging, true);
            app.tau2.Tooltip = {'Torque para el segundo grado de libertad.'};
            app.Label_4 = uilabel(app.SettingsTab);
            app.Label_4.Text = 'τ3';
            app.tau3 = uislider(app.SettingsTab);
            app.tau3.Limits = [-0.1 0.1];
            app.tau3.ValueChangingFcn = createCallbackFcn(app, @tau3ValueChanging, true);
            app.tau3.Tooltip = {'Torque para el tercer grado de libertad.'};
            app.Label_5 = uilabel(app.SettingsTab);
            app.Label_5.Text = 'τ4';
            app.tau4 = uislider(app.SettingsTab);
            app.tau4.Limits = [-0.1 0.1];
            app.tau4.ValueChangingFcn = createCallbackFcn(app, @tau4ValueChanging, true);
            app.tau4.Tooltip = {'Torque para el cuarto grado de libertad.'};
            app.Label_6 = uilabel(app.SettingsTab);
            app.Label_6.Text = 'τ5';
            app.tau5 = uislider(app.SettingsTab);
            app.tau5.Limits = [-0.1 0.1];
            app.tau5.ValueChangingFcn = createCallbackFcn(app, @tau5ValueChanging, true);
            app.tau5.Tooltip = {'Torque para el quinto grado de libertad.'};
            app.Label_7 = uilabel(app.SettingsTab);
            app.Label_7.Text = 'τ6';
            app.tau6 = uislider(app.SettingsTab);
            app.tau6.Limits = [-0.1 0.1];
            app.tau6.ValueChangingFcn = createCallbackFcn(app, @tau6ValueChanging, true);
            app.tau6.Tooltip = {'Torque para el sexto grado de libertad.'};
            app.ReestablecertorquesButton = uibutton(app.SettingsTab, 'push');
            app.ReestablecertorquesButton.ButtonPushedFcn = createCallbackFcn(app, @ReestablecertorquesButtonPushed, true);
            app.ReestablecertorquesButton.Tooltip = {'Todos los torques se declaran cero. Respuesta libre del sistema.'};
            app.ReestablecertorquesButton.Text = 'Reestablecer torques';
            app.TorquesLabel = uilabel(app.SettingsTab);
            app.TorquesLabel.Text = 'Torques';
            app.TiempodesimulacinEditFieldLabel = uilabel(app.SettingsTab);
            app.TiempodesimulacinEditFieldLabel.Text = 'Tiempo de simulación';
            app.SimulationTime = uieditfield(app.SettingsTab, 'numeric');
            app.SimulationTime.Limits = [5 200];
            app.SimulationTime.Tooltip = {'Tiempo de simulación. Valor entre 5 a 200 [s]'};
            app.SimulationTime.Value = 10;
            app.TipodesistemaButtonGroup = uibuttongroup(app.SettingsTab);
            app.TipodesistemaButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @TipodesistemaButtonGroupSelectionChanged, true);
            app.TipodesistemaButtonGroup.Title = 'Tipo de sistema';
            app.ConservativoButton = uitogglebutton(app.TipodesistemaButtonGroup);
            app.ConservativoButton.Tooltip = {'La energía'; 'se conserva.'; ''; 'Parámetros: '; '- Factor de Disipación en 0 [Ns/m].'; '- Tiempo de simulación 200 [s]'};
            app.ConservativoButton.Text = 'Conservativo';
            app.NoconservativoButton = uitogglebutton(app.TipodesistemaButtonGroup);
            app.NoconservativoButton.Tooltip = {'Existe disipación'; 'de energía'};
            app.NoconservativoButton.Text = 'No conservativo';
            app.NoconservativoButton.Value = true;
            app.prev_tq1 = uispinner(app.SettingsTab);
            app.prev_tq1.Step = 0.01;
            app.prev_tq1.ValueChangingFcn = createCallbackFcn(app, @prev_tq1ValueChanging, true);
            app.prev_tq1.Limits = [-0.1 0.1];
            app.prev_tq1.ValueDisplayFormat = '%.3f';
            app.prev_tq2 = uispinner(app.SettingsTab);
            app.prev_tq2.Step = 0.01;
            app.prev_tq2.ValueChangingFcn = createCallbackFcn(app, @prev_tq2ValueChanging, true);
            app.prev_tq2.Limits = [-0.1 0.1];
            app.prev_tq2.ValueDisplayFormat = '%.3f';
            app.prev_tq3 = uispinner(app.SettingsTab);
            app.prev_tq3.Step = 0.01;
            app.prev_tq3.ValueChangingFcn = createCallbackFcn(app, @prev_tq3ValueChanging, true);
            app.prev_tq3.Limits = [-0.1 0.1];
            app.prev_tq3.ValueDisplayFormat = '%.3f';
            app.prev_tq4 = uispinner(app.SettingsTab);
            app.prev_tq4.Step = 0.01;
            app.prev_tq4.ValueChangingFcn = createCallbackFcn(app, @prev_tq4ValueChanging, true);
            app.prev_tq4.Limits = [-0.1 0.1];
            app.prev_tq4.ValueDisplayFormat = '%.3f';
            app.prev_tq5 = uispinner(app.SettingsTab);
            app.prev_tq5.Step = 0.01;
            app.prev_tq5.ValueChangingFcn = createCallbackFcn(app, @prev_tq5ValueChanging, true);
            app.prev_tq5.Limits = [-0.1 0.1];
            app.prev_tq5.ValueDisplayFormat = '%.3f';
            app.FactordedisipacinLabel = uilabel(app.SettingsTab);
            app.FactordedisipacinLabel.Text = 'Factor de disipación';
            app.Friction = uiknob(app.SettingsTab, 'continuous');
            app.Friction.Limits = [0 0.01];
            app.Friction.ValueChangingFcn = createCallbackFcn(app, @FrictionValueChanging, true);
            app.Friction.Tooltip = {'Factor de disipación [Ns/m] '};
            app.Friction.Value = 0.004;
            app.prev_tq6 = uispinner(app.SettingsTab);
            app.prev_tq6.Step = 0.01;
            app.prev_tq6.ValueChangingFcn = createCallbackFcn(app, @prev_tq6ValueChanging, true);
            app.prev_tq6.Limits = [-0.1 0.1];
            app.prev_tq6.ValueDisplayFormat = '%.3f';
            app.friction_disp = uieditfield(app.SettingsTab, 'numeric');
            app.friction_disp.Editable = 'off';
            app.friction_disp.Value = 0.004;
            app.Image = uiimage(app.SettingsTab);
            app.Image.ImageSource = 'ryma_icon.png';
            app.SimuladorDinmicoLabel = uilabel(app.SettingsTab);
            app.SimuladorDinmicoLabel.Text = 'Simulador Dinámico';
            app.Exoesqueletode6GDLLabel = uilabel(app.SettingsTab);
            app.Exoesqueletode6GDLLabel.Text = 'Exoesqueleto de 6GDL';
            app.sLabel = uilabel(app.SettingsTab);
            app.sLabel.Text = '[s]';
            app.NsmLabel = uilabel(app.SettingsTab);
            app.NsmLabel.Text = '[Ns/m]';
            app.Image2 = uiimage(app.SettingsTab);
            app.Image2.Tooltip = {'Simulador desarrollado por:'; '- García Álvarez Gregorio Eliezer'; '- Luna Macías Antonio de Jesús'; '- Tevera Ruiz Alejandro'};
            app.Image2.ImageSource = 'question_icon.png';
            app.NmLabel = uilabel(app.SettingsTab);
            app.NmLabel.Text = '[Nm]';
            app.VariablesdeEstadoTab = uitab(app.TabGroup);
            app.VariablesdeEstadoTab.Title = 'Variables de Estado';
            app.graph_velocity = uiaxes(app.VariablesdeEstadoTab);
            title(app.graph_velocity, 'Velocidades Generalizadas')
            xlabel(app.graph_velocity, 'Tiempo: [s]')
            ylabel(app.graph_velocity, '[ rad / s ]')
            zlabel(app.graph_velocity, 'Z')
            app.graph_velocity.XLimitMethod = 'tight';
            app.graph_velocity.YLimitMethod = 'tight';
            app.graph_position = uiaxes(app.VariablesdeEstadoTab);
            title(app.graph_position, 'Coordenadas Generalizadas')
            xlabel(app.graph_position, 'Tiempo: [s]')
            ylabel(app.graph_position, '[ rad ]')
            zlabel(app.graph_position, 'Z')
            app.graph_position.YLimitMethod = 'tight';
            app.Label = uilabel(app.VariablesdeEstadoTab);
            app.Label.Text = ' ';
            app.EnergyAnalysisTab = uitab(app.TabGroup);
            app.EnergyAnalysisTab.Title = 'Energía Cinética y Potencial';
            app.graph_energy_kinetic = uiaxes(app.EnergyAnalysisTab);
            title(app.graph_energy_kinetic, 'Energía Cinética')
            xlabel(app.graph_energy_kinetic, 'Tiempo: [s]')
            ylabel(app.graph_energy_kinetic, '[J]')
            zlabel(app.graph_energy_kinetic, 'Z')
            app.graph_energy_kinetic.YLimitMethod = 'tight';
            app.graph_energy_potential = uiaxes(app.EnergyAnalysisTab);
            title(app.graph_energy_potential, 'Energía Potencial')
            xlabel(app.graph_energy_potential, 'Tiempo: [s]')
            ylabel(app.graph_energy_potential, '[J]')
            zlabel(app.graph_energy_potential, 'Z')
            app.graph_energy_potential.XLimitMethod = 'tight';
            app.graph_energy_potential.YLimitMethod = 'tight';
            app.EnergaMecnicaTab = uitab(app.TabGroup);
            app.EnergaMecnicaTab.Title = 'Energía Mecánica';
            app.graph_energy_mechanic = uiaxes(app.EnergaMecnicaTab);
            title(app.graph_energy_mechanic, 'Análisis Energético')
            xlabel(app.graph_energy_mechanic, 'Tiempo: [s]')
            ylabel(app.graph_energy_mechanic, '[J]')
            zlabel(app.graph_energy_mechanic, 'Z')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = simulator_GUI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end