classdef SIM_EXO_DAL_GUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        TabGroup                       matlab.ui.container.TabGroup
        SettingsTab                    matlab.ui.container.Tab
        TabGroup2                      matlab.ui.container.TabGroup
        LazoAbiertoTab                 matlab.ui.container.Tab
        Image                          matlab.ui.control.Image
        Exoesqueletode6GDLLabel        matlab.ui.control.Label
        SimuladorDinmicoLabel          matlab.ui.control.Label
        NsmLabel                       matlab.ui.control.Label
        friction_disp                  matlab.ui.control.NumericEditField
        Friction                       matlab.ui.control.Knob
        FactordedisipacinLabel         matlab.ui.control.Label
        TipodesistemaButtonGroup       matlab.ui.container.ButtonGroup
        NoconservativoButton           matlab.ui.control.ToggleButton
        ConservativoButton             matlab.ui.control.ToggleButton
        NmLabel                        matlab.ui.control.Label
        prev_tq6                       matlab.ui.control.Spinner
        prev_tq5                       matlab.ui.control.Spinner
        prev_tq4                       matlab.ui.control.Spinner
        prev_tq3                       matlab.ui.control.Spinner
        prev_tq2                       matlab.ui.control.Spinner
        prev_tq1                       matlab.ui.control.Spinner
        TorquesLabel                   matlab.ui.control.Label
        ReestablecertorquesButton      matlab.ui.control.Button
        tau6                           matlab.ui.control.Slider
        Label_7                        matlab.ui.control.Label
        tau5                           matlab.ui.control.Slider
        Label_6                        matlab.ui.control.Label
        tau4                           matlab.ui.control.Slider
        Label_5                        matlab.ui.control.Label
        tau3                           matlab.ui.control.Slider
        Label_4                        matlab.ui.control.Label
        tau2                           matlab.ui.control.Slider
        Label_3                        matlab.ui.control.Label
        tau1                           matlab.ui.control.Slider
        Label_2                        matlab.ui.control.Label
        LazoCerradoTab                 matlab.ui.container.Tab
        TiempodeasentamientoEditField  matlab.ui.control.NumericEditField
        TiempodeasentamientoEditFieldLabel  matlab.ui.control.Label
        ControlaaplicarDropDown        matlab.ui.control.DropDown
        ControlaaplicarDropDownLabel   matlab.ui.control.Label
        q_deseada                      matlab.ui.control.EditField
        textbfqdeseadaLabel            matlab.ui.control.Label
        Image2                         matlab.ui.control.Image
        sLabel                         matlab.ui.control.Label
        SimulationTime                 matlab.ui.control.NumericEditField
        TiempodesimulacinEditFieldLabel  matlab.ui.control.Label
        SimularButton                  matlab.ui.control.Button
        VariablesdeEstadoTab           matlab.ui.container.Tab
        Label                          matlab.ui.control.Label
        graph_position                 matlab.ui.control.UIAxes
        graph_velocity                 matlab.ui.control.UIAxes
        EnergyAnalysisTab              matlab.ui.container.Tab
        graph_energy_potential         matlab.ui.control.UIAxes
        graph_energy_kinetic           matlab.ui.control.UIAxes
        EnergaMecnicaTab               matlab.ui.container.Tab
        graph_energy_mechanic          matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        friction_tmp
        control % Description
        q_d % Description
        ts % Description
        tau % Description
    end
    
    properties (Access = public)
    end
    
    methods (Access = private)
        
        
        function show_scopes(~)
            model = 'SIM_EXO_DAL';
            open_system([model,'/Scope'])
            open_system([model,'/Scope1'])
            open_system([model,'/Scope2'])
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            clear all; close all; clc
            app.ts = 1;
            app.tau = [0 0 0 0 0 0];
            app.q_d = [0 0 0 0 0 0];
            app.control = 100;
            app.friction_tmp = 0.1;
            
            assignin("base", "control", [0 app.control]);
            assignin("base", "dissipative", [0 app.friction_tmp]);
            assignin("base", "q_d", [0 app.q_d]);
            assignin("base", "ts", [0 app.ts]);
            assignin("base", "torque", [0 app.tau]);
        end

        % Button pushed function: SimularButton
        function SimularButtonPushed(app, event)
            clc
            app.SimularButton.Enable = 'off';
            app.SimularButton.Text = 'Simulando ... ';
            app.SimularButton.BackgroundColor = [0.00,0.62,0.58];
            
            hold(app.graph_energy_kinetic,'off')
      
            tq1 = app.tau1.Value;
            tq2 = app.tau2.Value;
            tq3 = app.tau3.Value;
            tq4 = app.tau4.Value;
            tq5 = app.tau5.Value;
            tq6 = app.tau6.Value;

            torque = [0 tq1 tq2 tq3 tq4 tq5 tq6];
            

            ts_temp = [0 app.ts];

            assignin("base", "torque", torque);
            assignin("base", "control", [0 app.control]);
            assignin("base", "q_d", [0 app.q_d]);
            assignin("base", "ts", ts_temp);

            app.friction_tmp = app.Friction.Value;
            selectedButton = app.TipodesistemaButtonGroup.SelectedObject;
            
            if selectedButton.Text == "Conservativo"
                assignin("base", "dissipative", [0 0]);
                assignin("base", "torque", [0 0 0 0 0 0 0]);
                
            else
                assignin("base", "dissipative", [0 app.friction_tmp]);
                assignin("base", "torque", torque);
            end

            sim_out = sim('SIM_EXO_DAL.slx', 'StopTime', num2str(app.SimulationTime.Value));
            
            n = size(sim_out.position.Time,1);
            plot(app.graph_position, sim_out.position.Time, reshape(sim_out.position.Data(:,1,:),6,n));
            legend(app.graph_position, {'$q_1$','$q_2$','$q_3$','$q_4$','$q_5$','$q_6$'},'Interpreter','latex');
            plot(app.graph_velocity, sim_out.velocity.Time, reshape(sim_out.velocity.Data(:,1,:),6,n));
            legend(app.graph_velocity, {'$\dot{q}_1$','$\dot{q}_2$','$\dot{q}_3$','$\dot{q}_4$','$\dot{q}_5$','$\dot{q}_6$'},'Interpreter','latex');
            
            %Energy Analysis
            plot(app.graph_energy_kinetic, sim_out.kinetic_energy.Time, sim_out.kinetic_energy.Data);
            plot(app.graph_energy_potential, sim_out.potential_energy.Time, sim_out.potential_energy.Data);

            plot(app.graph_energy_mechanic, sim_out.mechanic_energy.Time, sim_out.mechanic_energy.Data);
            hold(app.graph_energy_mechanic, 'on')
            plot(app.graph_energy_mechanic, sim_out.potential_energy.Time, sim_out.potential_energy.Data);
            plot(app.graph_energy_mechanic, sim_out.kinetic_energy.Time, sim_out.kinetic_energy.Data);
            legend(app.graph_energy_mechanic, {'Energía Mecánica', 'Energía Potencial', 'Energía Cinética'})
            hold(app.graph_energy_mechanic, 'off')
            
            %app.show_scopes()

            app.SimularButton.Enable = 'on';
            app.SimularButton.Text = 'Simular';
            app.SimularButton.BackgroundColor = [0.96,0.96,0.96];
        end

        % Button pushed function: ReestablecertorquesButton
        function ReestablecertorquesButtonPushed(app, event)
            app.tau_string.Value = '0,0,0,0,0,0';
            app.tau = [0 0 0 0 0 0];
        end

        % Selection changed function: TipodesistemaButtonGroup
        function TipodesistemaButtonGroupSelectionChanged(app, event)
            selectedButton = app.TipodesistemaButtonGroup.SelectedObject;
            
            if selectedButton.Text == "Conservativo"
                app.Friction.Enable = 'Off';
                app.friction_disp.Enable = 'Off';
                app.friction_tmp = app.Friction.Value;
                app.friction_disp.Value = 0;
                app.Friction.Value = 0;
                app.tau = [0 0 0 0 0 0];

            else
                app.Friction.Enable = 'On';
                app.friction_disp.Enable = 'On';
                app.Friction.Value = app.friction_tmp;
                app.friction_disp.Value = app.friction_tmp;
            end
        end

        % Value changing function: tau1
        function tau1ValueChanging(app, event)
            changingValue = event.Value;
            app.prev_tq1.Value = changingValue;
        end

        % Value changing function: prev_tq1
        function prev_tq1ValueChanging(app, event)
            app.tau1.Value = event.Value;
        end

        % Value changing function: tau2
        function tau2ValueChanging(app, event)
            app.prev_tq2.Value = event.Value;
        end

        % Value changing function: prev_tq2
        function prev_tq2ValueChanging(app, event)
            app.tau2.Value = event.Value;            
        end

        % Value changing function: tau3
        function tau3ValueChanging(app, event)
            app.prev_tq3.Value = event.Value;
        end

        % Value changing function: prev_tq3
        function prev_tq3ValueChanging(app, event)
            app.tau3.Value = event.Value;            
        end

        % Value changing function: tau4
        function tau4ValueChanging(app, event)
            app.prev_tq4.Value = event.Value;
        end

        % Value changing function: prev_tq4
        function prev_tq4ValueChanging(app, event)
            app.tau4.Value = event.Value;            
        end

        % Value changing function: tau5
        function tau5ValueChanging(app, event)
            app.prev_tq5.Value = event.Value;            
        end

        % Value changing function: prev_tq5
        function prev_tq5ValueChanging(app, event)
            app.tau5.Value = event.Value;            
        end

        % Value changing function: tau6
        function tau6ValueChanging(app, event)
            app.prev_tq6.Value = event.Value;            
        end

        % Value changing function: prev_tq6
        function prev_tq6ValueChanging(app, event)
            app.tau6.Value = event.Value;            
        end

        % Value changing function: Friction
        function FrictionValueChanging(app, event)
            app.friction_disp.Value = event.Value;            
        end

        % Callback function
        function selector_controlSelectionChanged(app, event)
            selectedButton = app.selector_control.SelectedObject;
            switch selectedButton
                case 1
                    app.control = 5;
                case 2
                    app.control = 4;
                case 3
                    app.control = 3;
                case 4
                    app.control = 2;
                case 5
                    app.control = 1;
                otherwise
                    app.control = 100;
            end
        end

        % Button down function: LazoAbiertoTab
        function LazoAbiertoTabButtonDown(app, event)
            app.control = 100;
        end

        % Button down function: LazoCerradoTab
        function LazoCerradoTabButtonDown(app, event)
            app.control = 5;
            assignin("base", "dissipative", [0 app.friction_tmp]);
        end

        % Value changed function: q_deseada
        function q_deseadaValueChanged(app, event)
            value = app.q_deseada.Value;
            app.q_d = deg2rad(str2double(strsplit(value,',')));
        end

        % Callback function
        function selector_controlButtonDown(app, event)
            selectedButton = app.selector_control.SelectedObject;
            switch selectedButton
                case 1
                    app.control = 5;
                case 2
                    app.control = 4;
                case 3
                    app.control = 3;
                case 4
                    app.control = 2;
                case 5
                    app.control = 1;
                otherwise
                    app.control = 100;
            end
        end

        % Value changed function: ControlaaplicarDropDown
        function ControlaaplicarDropDownValueChanged(app, event)
            value = app.ControlaaplicarDropDown.Value;
            switch value
                case 'PD'
                    app.control = 5;
                case 'PD+g (E)'
                    app.control = 4;
                case 'PD+g (D)'
                    app.control = 3;
                case 'PID (LGR)'
                    app.control = 2;
                case 'PID (SNT)'
                    app.control = 1;
                otherwise
                    app.control = 100;
            end
        end

        % Value changed function: TiempodeasentamientoEditField
        function TiempodeasentamientoEditFieldValueChanged(app, event)
            value = app.TiempodeasentamientoEditField.Value;
            app.ts = value;            
        end

        % Callback function
        function tau_stringValueChanged(app, event)
            value = app.tau_string.Value;
            app.tau = str2double(strsplit(value,','));
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.SettingsTab = uitab(app.TabGroup);
            app.SettingsTab.Title = 'Configuración';
            app.SimularButton = uibutton(app.SettingsTab, 'push');
            app.SimularButton.ButtonPushedFcn = createCallbackFcn(app, @SimularButtonPushed, true);
            app.SimularButton.Tooltip = {'Ejecución de simulación con los parámetros establecidos previamente.'};
            app.SimularButton.Text = 'Simular';
            app.TiempodesimulacinEditFieldLabel = uilabel(app.SettingsTab);
            app.TiempodesimulacinEditFieldLabel.Text = 'Tiempo de simulación';
            app.SimulationTime = uieditfield(app.SettingsTab, 'numeric');
            app.SimulationTime.Limits = [5 200];
            app.SimulationTime.Tooltip = {'Tiempo de simulación. Valor entre 5 a 200 [s]'};
            app.SimulationTime.Value = 10;
            app.sLabel = uilabel(app.SettingsTab);
            app.sLabel.Text = '[s]';
            app.Image2 = uiimage(app.SettingsTab);
            app.Image2.Tooltip = {'Simulador desarrollado por:'; '- García Álvarez Gregorio Eliezer'; '- Luna Macías Antonio de Jesús'; '- Tevera Ruiz Alejandro'};
            app.Image2.ImageSource = 'question_icon.png';
            app.TabGroup2 = uitabgroup(app.SettingsTab);
            app.LazoAbiertoTab = uitab(app.TabGroup2);
            app.LazoAbiertoTab.Title = 'Lazo Abierto';
            app.LazoAbiertoTab.ButtonDownFcn = createCallbackFcn(app, @LazoAbiertoTabButtonDown, true);
            app.Label_2 = uilabel(app.LazoAbiertoTab);
            app.Label_2.Text = 'τ1';
            app.tau1 = uislider(app.LazoAbiertoTab);
            app.tau1.Limits = [-0.1 0.1];
            app.tau1.ValueChangingFcn = createCallbackFcn(app, @tau1ValueChanging, true);
            app.tau1.Tooltip = {'Torque para el primer grado de libertad.'};
            app.Label_3 = uilabel(app.LazoAbiertoTab);
            app.Label_3.Text = 'τ2';
            app.tau2 = uislider(app.LazoAbiertoTab);
            app.tau2.Limits = [-0.1 0.1];
            app.tau2.ValueChangingFcn = createCallbackFcn(app, @tau2ValueChanging, true);
            app.tau2.Tooltip = {'Torque para el segundo grado de libertad.'};
            app.Label_4 = uilabel(app.LazoAbiertoTab);
            app.Label_4.Text = 'τ3';
            app.tau3 = uislider(app.LazoAbiertoTab);
            app.tau3.Limits = [-0.1 0.1];
            app.tau3.ValueChangingFcn = createCallbackFcn(app, @tau3ValueChanging, true);
            app.tau3.Tooltip = {'Torque para el tercer grado de libertad.'};
            app.Label_5 = uilabel(app.LazoAbiertoTab);
            app.Label_5.Text = 'τ4';
            app.tau4 = uislider(app.LazoAbiertoTab);
            app.tau4.Limits = [-0.1 0.1];
            app.tau4.ValueChangingFcn = createCallbackFcn(app, @tau4ValueChanging, true);
            app.tau4.Tooltip = {'Torque para el cuarto grado de libertad.'};
            app.Label_6 = uilabel(app.LazoAbiertoTab);
            app.Label_6.Text = 'τ5';
            app.tau5 = uislider(app.LazoAbiertoTab);
            app.tau5.Limits = [-0.1 0.1];
            app.tau5.ValueChangingFcn = createCallbackFcn(app, @tau5ValueChanging, true);
            app.tau5.Tooltip = {'Torque para el quinto grado de libertad.'};
            app.Label_7 = uilabel(app.LazoAbiertoTab);
            app.Label_7.Text = 'τ6';
            app.tau6 = uislider(app.LazoAbiertoTab);
            app.tau6.Limits = [-0.1 0.1];
            app.tau6.ValueChangingFcn = createCallbackFcn(app, @tau6ValueChanging, true);
            app.tau6.Tooltip = {'Torque para el sexto grado de libertad.'};
            app.ReestablecertorquesButton = uibutton(app.LazoAbiertoTab, 'push');
            app.ReestablecertorquesButton.ButtonPushedFcn = createCallbackFcn(app, @ReestablecertorquesButtonPushed, true);
            app.ReestablecertorquesButton.Tooltip = {'Todos los torques se declaran cero. Respuesta libre del sistema.'};
            app.ReestablecertorquesButton.Text = 'Reestablecer torques';
            app.TorquesLabel = uilabel(app.LazoAbiertoTab);
            app.TorquesLabel.Text = 'Torques';
            app.prev_tq1 = uispinner(app.LazoAbiertoTab);
            app.prev_tq1.Step = 0.01;
            app.prev_tq1.ValueChangingFcn = createCallbackFcn(app, @prev_tq1ValueChanging, true);
            app.prev_tq1.Limits = [-0.1 0.1];
            app.prev_tq1.ValueDisplayFormat = '%.3f';
            app.prev_tq2 = uispinner(app.LazoAbiertoTab);
            app.prev_tq2.Step = 0.01;
            app.prev_tq2.ValueChangingFcn = createCallbackFcn(app, @prev_tq2ValueChanging, true);
            app.prev_tq2.Limits = [-0.1 0.1];
            app.prev_tq2.ValueDisplayFormat = '%.3f';
            app.prev_tq3 = uispinner(app.LazoAbiertoTab);
            app.prev_tq3.Step = 0.01;
            app.prev_tq3.ValueChangingFcn = createCallbackFcn(app, @prev_tq3ValueChanging, true);
            app.prev_tq3.Limits = [-0.1 0.1];
            app.prev_tq3.ValueDisplayFormat = '%.3f';
            app.prev_tq4 = uispinner(app.LazoAbiertoTab);
            app.prev_tq4.Step = 0.01;
            app.prev_tq4.ValueChangingFcn = createCallbackFcn(app, @prev_tq4ValueChanging, true);
            app.prev_tq4.Limits = [-0.1 0.1];
            app.prev_tq4.ValueDisplayFormat = '%.3f';
            app.prev_tq5 = uispinner(app.LazoAbiertoTab);
            app.prev_tq5.Step = 0.01;
            app.prev_tq5.ValueChangingFcn = createCallbackFcn(app, @prev_tq5ValueChanging, true);
            app.prev_tq5.Limits = [-0.1 0.1];
            app.prev_tq5.ValueDisplayFormat = '%.3f';
            app.prev_tq6 = uispinner(app.LazoAbiertoTab);
            app.prev_tq6.Step = 0.01;
            app.prev_tq6.ValueChangingFcn = createCallbackFcn(app, @prev_tq6ValueChanging, true);
            app.prev_tq6.Limits = [-0.1 0.1];
            app.prev_tq6.ValueDisplayFormat = '%.3f';
            app.NmLabel = uilabel(app.LazoAbiertoTab);
            app.NmLabel.Text = '[Nm]';
            app.TipodesistemaButtonGroup = uibuttongroup(app.LazoAbiertoTab);
            app.TipodesistemaButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @TipodesistemaButtonGroupSelectionChanged, true);
            app.TipodesistemaButtonGroup.Title = 'Tipo de sistema';
            app.ConservativoButton = uitogglebutton(app.TipodesistemaButtonGroup);
            app.ConservativoButton.Tooltip = {'La energía'; 'se conserva.'; ''; 'Parámetros: '; '- Factor de Disipación en 0 [Ns/m].'; '- Tiempo de simulación 200 [s]'};
            app.ConservativoButton.Text = 'Conservativo';
            app.NoconservativoButton = uitogglebutton(app.TipodesistemaButtonGroup);
            app.NoconservativoButton.Tooltip = {'Existe disipación'; 'de energía'};
            app.NoconservativoButton.Text = 'No conservativo';
            app.NoconservativoButton.Value = true;
            app.FactordedisipacinLabel = uilabel(app.LazoAbiertoTab);
            app.FactordedisipacinLabel.Text = 'Factor de disipación';
            app.Friction = uiknob(app.LazoAbiertoTab, 'continuous');
            app.Friction.Limits = [0 0.01];
            app.Friction.ValueChangingFcn = createCallbackFcn(app, @FrictionValueChanging, true);
            app.Friction.Tooltip = {'Factor de disipación [Ns/m] '};
            app.Friction.Value = 0.004;
            app.friction_disp = uieditfield(app.LazoAbiertoTab, 'numeric');
            app.friction_disp.Editable = 'off';
            app.friction_disp.Value = 0.004;
            app.NsmLabel = uilabel(app.LazoAbiertoTab);
            app.NsmLabel.Text = '[Ns/m]';
            app.SimuladorDinmicoLabel = uilabel(app.LazoAbiertoTab);
            app.SimuladorDinmicoLabel.Text = 'Simulador Dinámico';
            app.Exoesqueletode6GDLLabel = uilabel(app.LazoAbiertoTab);
            app.Exoesqueletode6GDLLabel.Text = 'Exoesqueleto de 6GDL';
            app.Image = uiimage(app.LazoAbiertoTab);
            app.Image.ImageSource = 'ryma_icon.png';
            app.LazoCerradoTab = uitab(app.TabGroup2);
            app.LazoCerradoTab.Title = 'Lazo Cerrado';
            app.LazoCerradoTab.ButtonDownFcn = createCallbackFcn(app, @LazoCerradoTabButtonDown, true);
            app.textbfqdeseadaLabel = uilabel(app.LazoCerradoTab);
            app.textbfqdeseadaLabel.Interpreter = 'latex';
            app.textbfqdeseadaLabel.Text = {'\textbf{q} \;\; \; deseada'; ''};
            app.q_deseada = uieditfield(app.LazoCerradoTab, 'text');
            app.q_deseada.ValueChangedFcn = createCallbackFcn(app, @q_deseadaValueChanged, true);
            app.q_deseada.Value = '0,0,0,0,0,0';
            app.ControlaaplicarDropDownLabel = uilabel(app.LazoCerradoTab);
            app.ControlaaplicarDropDownLabel.Text = 'Control a aplicar';
            app.ControlaaplicarDropDown = uidropdown(app.LazoCerradoTab);
            app.ControlaaplicarDropDown.Items = {'PD', 'PD+g (E)', 'PD+g (D)', 'PID (LGR)', 'PID (SNT)'};
            app.ControlaaplicarDropDown.ValueChangedFcn = createCallbackFcn(app, @ControlaaplicarDropDownValueChanged, true);
            app.ControlaaplicarDropDown.Value = 'PD';
            app.TiempodeasentamientoEditFieldLabel = uilabel(app.LazoCerradoTab);
            app.TiempodeasentamientoEditFieldLabel.Text = 'Tiempo de asentamiento';
            app.TiempodeasentamientoEditField = uieditfield(app.LazoCerradoTab, 'numeric');
            app.TiempodeasentamientoEditField.ValueChangedFcn = createCallbackFcn(app, @TiempodeasentamientoEditFieldValueChanged, true);
            app.TiempodeasentamientoEditField.Value = 1;
            app.VariablesdeEstadoTab = uitab(app.TabGroup);
            app.VariablesdeEstadoTab.Title = 'Variables de Estado';
            app.graph_velocity = uiaxes(app.VariablesdeEstadoTab);
            title(app.graph_velocity, 'Velocidades Generalizadas')
            xlabel(app.graph_velocity, 'Tiempo: [s]')
            ylabel(app.graph_velocity, '[ rad / s ]')
            zlabel(app.graph_velocity, 'Z')
            app.graph_velocity.XLimitMethod = 'tight';
            app.graph_velocity.YLimitMethod = 'tight';
            app.graph_position = uiaxes(app.VariablesdeEstadoTab);
            title(app.graph_position, 'Coordenadas Generalizadas')
            xlabel(app.graph_position, 'Tiempo: [s]')
            ylabel(app.graph_position, '[ rad ]')
            zlabel(app.graph_position, 'Z')
            app.graph_position.YLimitMethod = 'tight';
            app.Label = uilabel(app.VariablesdeEstadoTab);
            app.Label.Text = ' ';
            app.EnergyAnalysisTab = uitab(app.TabGroup);
            app.EnergyAnalysisTab.Title = 'Energía Cinética y Potencial';
            app.graph_energy_kinetic = uiaxes(app.EnergyAnalysisTab);
            title(app.graph_energy_kinetic, 'Energía Cinética')
            xlabel(app.graph_energy_kinetic, 'Tiempo: [s]')
            ylabel(app.graph_energy_kinetic, '[J]')
            zlabel(app.graph_energy_kinetic, 'Z')
            app.graph_energy_kinetic.YLimitMethod = 'tight';
            app.graph_energy_potential = uiaxes(app.EnergyAnalysisTab);
            title(app.graph_energy_potential, 'Energía Potencial')
            xlabel(app.graph_energy_potential, 'Tiempo: [s]')
            ylabel(app.graph_energy_potential, '[J]')
            zlabel(app.graph_energy_potential, 'Z')
            app.graph_energy_potential.XLimitMethod = 'tight';
            app.graph_energy_potential.YLimitMethod = 'tight';
            app.EnergaMecnicaTab = uitab(app.TabGroup);
            app.EnergaMecnicaTab.Title = 'Energía Mecánica';
            app.graph_energy_mechanic = uiaxes(app.EnergaMecnicaTab);
            title(app.graph_energy_mechanic, 'Análisis Energético')
            xlabel(app.graph_energy_mechanic, 'Tiempo: [s]')
            ylabel(app.graph_energy_mechanic, '[J]')
            zlabel(app.graph_energy_mechanic, 'Z')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = SIM_EXO_DAL_GUI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end