classdef simulator_GUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        TabGroup                    matlab.ui.container.TabGroup
        SettingsTab                 matlab.ui.container.Tab
        DissipativeFactorEditField  matlab.ui.control.NumericEditField
        DissipativeFactorEditFieldLabel  matlab.ui.control.Label
        FrictionSwitch              matlab.ui.control.Switch
        FrictionSwitchLabel         matlab.ui.control.Label
        CambiospropuestosSlidersaCasillasconlmitesparaprecisinLabel  matlab.ui.control.Label
        AnalysisTimeEditField       matlab.ui.control.NumericEditField
        AnalysisTimeEditFieldLabel  matlab.ui.control.Label
        TorquesLabel                matlab.ui.control.Label
        SetZerosButton              matlab.ui.control.Button
        EditField_6                 matlab.ui.control.NumericEditField
        Label_7                     matlab.ui.control.Label
        EditField_5                 matlab.ui.control.NumericEditField
        Label_6                     matlab.ui.control.Label
        EditField_4                 matlab.ui.control.NumericEditField
        Label_5                     matlab.ui.control.Label
        EditField_3                 matlab.ui.control.NumericEditField
        Label_4                     matlab.ui.control.Label
        EditField_2                 matlab.ui.control.NumericEditField
        Label_3                     matlab.ui.control.Label
        SimularButton               matlab.ui.control.Button
        EditField                   matlab.ui.control.NumericEditField
        Label_2                     matlab.ui.control.Label
        GraphicsTab                 matlab.ui.container.Tab
        Label                       matlab.ui.control.Label
        graph_velocity              matlab.ui.control.UIAxes
        graph_position              matlab.ui.control.UIAxes
        EnergyAnalysisTab           matlab.ui.container.Tab
        graph_energy_mechanic       matlab.ui.control.UIAxes
        graph_energy_potential      matlab.ui.control.UIAxes
        graph_energy_kinetic        matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: SimularButton
        function SimularButtonPushed(app, event)
            clear All
            hold(app.graph_energy_kinetic,'off')
            tq1 = [0 app.EditField.Value];
            tq2 = [0 app.EditField_2.Value];
            tq3 = [0 app.EditField_3.Value];
            tq4 = [0 app.EditField_4.Value];
            tq5 = [0 app.EditField_5.Value];
            tq6 = [0 app.EditField_6.Value];

            assignin("base", "tq1", tq1);
            assignin("base", "tq2", tq2);
            assignin("base", "tq3", tq3);
            assignin("base", "tq4", tq4);
            assignin("base", "tq5", tq5);
            assignin("base", "tq6", tq6);

            value = app.FrictionSwitch.Value;
            if strcmp(value, 'On')
                assignin("base", "dissipative", [0 app.DissipativeFactorEditField.Value]);
            else
                assignin("base", "dissipative", [0 1e-6]);
            end

            sim_out = sim('simulator_prototype.slx', 'StopTime', num2str(app.AnalysisTimeEditField.Value));

            plot(app.graph_position, sim_out.position.Time, sim_out.position.Data);
            plot(app.graph_velocity, sim_out.velocity.Time, sim_out.velocity.Data);
            
            %Energy Analysis
            plot(app.graph_energy_kinetic, sim_out.kinetic_energy.Time, sim_out.kinetic_energy.Data);
                % Potential Energy Offset
            potential_energy = sim_out.potential_energy.Data;

            offset = abs(min(potential_energy));
            samples = length(potential_energy);
            potential_energy_offset = zeros(samples, 1);
            
            for i = 1:samples
                potential_energy_offset(i) = potential_energy(i) + offset;
            end
            mechanic_energy = sim_out.kinetic_energy.Data + potential_energy_offset;

            plot(app.graph_energy_potential, sim_out.potential_energy.Time, potential_energy_offset);
            plot(app.graph_energy_mechanic, sim_out.potential_energy.Time, mechanic_energy);
            
        end

        % Button pushed function: SetZerosButton
        function SetZerosButtonPushed(app, event)
            app.EditField.Value = 0;
            app.EditField_2.Value = 0;
            app.EditField_3.Value = 0;
            app.EditField_4.Value = 0;
            app.EditField_5.Value = 0;
            app.EditField_6.Value = 0;
        end

        % Value changed function: FrictionSwitch
        function FrictionSwitchValueChanged(app, event)
            value = app.FrictionSwitch.Value;
            if strcmp(value, 'On')
                assignin("base", "friction", 1);
            else
                assignin("base", "friction", 1e-4);
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.SettingsTab = uitab(app.TabGroup);
            app.SettingsTab.Title = 'Settings';
            app.Label_2 = uilabel(app.SettingsTab);
            app.Label_2.Text = 'τ1';
            app.EditField = uieditfield(app.SettingsTab, 'numeric');
            app.EditField.Limits = [-1 1];
            app.SimularButton = uibutton(app.SettingsTab, 'push');
            app.SimularButton.ButtonPushedFcn = createCallbackFcn(app, @SimularButtonPushed, true);
            app.SimularButton.Text = 'Simular';
            app.Label_3 = uilabel(app.SettingsTab);
            app.Label_3.Text = 'τ2';
            app.EditField_2 = uieditfield(app.SettingsTab, 'numeric');
            app.EditField_2.Limits = [-1 1];
            app.Label_4 = uilabel(app.SettingsTab);
            app.Label_4.Text = 'τ3';
            app.EditField_3 = uieditfield(app.SettingsTab, 'numeric');
            app.EditField_3.Limits = [-1 1];
            app.Label_5 = uilabel(app.SettingsTab);
            app.Label_5.Text = 'τ4';
            app.EditField_4 = uieditfield(app.SettingsTab, 'numeric');
            app.EditField_4.Limits = [-1 1];
            app.Label_6 = uilabel(app.SettingsTab);
            app.Label_6.Text = 'τ5';
            app.EditField_5 = uieditfield(app.SettingsTab, 'numeric');
            app.EditField_5.Limits = [-1 1];
            app.Label_7 = uilabel(app.SettingsTab);
            app.Label_7.Text = 'τ6';
            app.EditField_6 = uieditfield(app.SettingsTab, 'numeric');
            app.EditField_6.Limits = [-1 1];
            app.SetZerosButton = uibutton(app.SettingsTab, 'push');
            app.SetZerosButton.ButtonPushedFcn = createCallbackFcn(app, @SetZerosButtonPushed, true);
            app.SetZerosButton.Text = 'Set Zeros';
            app.TorquesLabel = uilabel(app.SettingsTab);
            app.TorquesLabel.Text = 'Torques';
            app.AnalysisTimeEditFieldLabel = uilabel(app.SettingsTab);
            app.AnalysisTimeEditFieldLabel.Text = 'Analysis Time';
            app.AnalysisTimeEditField = uieditfield(app.SettingsTab, 'numeric');
            app.AnalysisTimeEditField.Limits = [5 60];
            app.AnalysisTimeEditField.Value = 5;
            app.CambiospropuestosSlidersaCasillasconlmitesparaprecisinLabel = uilabel(app.SettingsTab);
            app.CambiospropuestosSlidersaCasillasconlmitesparaprecisinLabel.Text = {'Cambios propuestos:'; '- Distribución de elementos'; '- Legends en gráficas'; '- Render del robot en pantalla principal'; '- Análisis de Energía'};
            app.FrictionSwitchLabel = uilabel(app.SettingsTab);
            app.FrictionSwitchLabel.Text = 'Friction';
            app.FrictionSwitch = uiswitch(app.SettingsTab, 'slider');
            app.FrictionSwitch.ValueChangedFcn = createCallbackFcn(app, @FrictionSwitchValueChanged, true);
            app.DissipativeFactorEditFieldLabel = uilabel(app.SettingsTab);
            app.DissipativeFactorEditFieldLabel.Text = 'Dissipative Factor';
            app.DissipativeFactorEditField = uieditfield(app.SettingsTab, 'numeric');
            app.DissipativeFactorEditField.Value = 0.1;
            app.GraphicsTab = uitab(app.TabGroup);
            app.GraphicsTab.Title = 'Graphics';
            app.graph_position = uiaxes(app.GraphicsTab);
            title(app.graph_position, 'Position')
            xlabel(app.graph_position, 'X')
            ylabel(app.graph_position, 'Y')
            zlabel(app.graph_position, 'Z')
            app.graph_position.YLimitMethod = 'tight';
            app.graph_velocity = uiaxes(app.GraphicsTab);
            title(app.graph_velocity, 'Velocity')
            xlabel(app.graph_velocity, 'X')
            ylabel(app.graph_velocity, 'Y')
            zlabel(app.graph_velocity, 'Z')
            app.graph_velocity.XLimitMethod = 'tight';
            app.graph_velocity.YLimitMethod = 'tight';
            app.Label = uilabel(app.GraphicsTab);
            app.Label.Text = ' ';
            app.EnergyAnalysisTab = uitab(app.TabGroup);
            app.EnergyAnalysisTab.Title = 'Energy Analysis';
            app.graph_energy_kinetic = uiaxes(app.EnergyAnalysisTab);
            title(app.graph_energy_kinetic, 'Kinetic Energy')
            xlabel(app.graph_energy_kinetic, 'X')
            ylabel(app.graph_energy_kinetic, 'Y')
            zlabel(app.graph_energy_kinetic, 'Z')
            app.graph_energy_potential = uiaxes(app.EnergyAnalysisTab);
            title(app.graph_energy_potential, 'Potential Energy')
            xlabel(app.graph_energy_potential, 'X')
            ylabel(app.graph_energy_potential, 'Y')
            zlabel(app.graph_energy_potential, 'Z')
            app.graph_energy_mechanic = uiaxes(app.EnergyAnalysisTab);
            title(app.graph_energy_mechanic, 'Mechanic Energy')
            xlabel(app.graph_energy_mechanic, 'X')
            ylabel(app.graph_energy_mechanic, 'Y')
            zlabel(app.graph_energy_mechanic, 'Z')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = simulator_GUI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end